classdef AppWSimulink < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        DoorPushTextArea            matlab.ui.control.TextArea
        DoorPushTextAreaLabel       matlab.ui.control.Label
        TextArea_5                  matlab.ui.control.TextArea
        TextArea_4                  matlab.ui.control.TextArea
        DirectionDown               matlab.ui.control.Button
        DirectionUp                 matlab.ui.control.Button
        Gauge                       matlab.ui.control.SemicircularGauge
        GaugeLabel                  matlab.ui.control.Label
        P1Button                    matlab.ui.control.Button
        GButton                     matlab.ui.control.Button
        AlarmStopButton             matlab.ui.control.Button
        AlarmStartButton            matlab.ui.control.Button
        resumeButton                matlab.ui.control.Button
        pauseButton                 matlab.ui.control.Button
        StopButton                  matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
        TestText                    matlab.ui.control.EditField
        TestTextLabel               matlab.ui.control.Label
        TextArea_3                  matlab.ui.control.TextArea
        ObjectDetectionButton       matlab.ui.control.Button
        Ext_User_Display            matlab.ui.control.EditField
        DoorStatusTextArea          matlab.ui.control.TextArea
        DoorStatusTextAreaLabel     matlab.ui.control.Label
        Door_Open                   matlab.ui.control.Button
        Door_Close                  matlab.ui.control.Button
        FloorSpinner                matlab.ui.control.Spinner
        FloorSpinnerLabel           matlab.ui.control.Label
        NoPassengersSpinner         matlab.ui.control.Spinner
        NoPassengersSpinnerLabel    matlab.ui.control.Label
        Lamp_2                      matlab.ui.control.Lamp
        DOWNButton                  matlab.ui.control.Button
        UPButton                    matlab.ui.control.Button
        NormalOperationButton       matlab.ui.control.Button
        TextArea_2                  matlab.ui.control.TextArea
        TextArea                    matlab.ui.control.TextArea
        Lamp                        matlab.ui.control.Lamp
        MOVINGButton                matlab.ui.control.Button
        STOPButton                  matlab.ui.control.Button
        Button_9                    matlab.ui.control.Button
        Button_8                    matlab.ui.control.Button
        Button_7                    matlab.ui.control.Button
        Button_6                    matlab.ui.control.Button
        Button_5                    matlab.ui.control.Button
        Button_4                    matlab.ui.control.Button
        Button_3                    matlab.ui.control.Button
        Button_2                    matlab.ui.control.Button
        CurrentFloorEditField       matlab.ui.control.EditField
        CurrentFloorEditFieldLabel  matlab.ui.control.Label
        FloorGauge                  matlab.ui.control.Gauge
    end


    properties (Access = private)
        chart % Description
        ObjectDetected;
        ReqFloor;
        liftCallDirection;
        Counter;
        FloorCounter;

        %       Audio Properties
        LM; % Lift_music
        Floor_1; % First floor
        AB;     % alarm bell
        DoorsOp; % Doors Opening
        DoorsCl; % Doors Closing
        Floor; % Floor
        AOne; % One
        ATwo; AThree; AFour; AFive; ASix; ASeven; AEight;
        AEmg;
    end

    methods (Access = public)

        function updateCounter(app, Cur_Floor, moving, DoorOperation)
            app.CurrentFloorEditField.Value = string(Cur_Floor);
            app.FloorGauge.Value = Cur_Floor;
            app.Gauge.Value = Cur_Floor;
            app.Ext_User_Display.Value = string(round(Cur_Floor));

            if moving == 1 % when moving the light should be on
                app.Lamp.Enable = 'on';
                app.DirectionUp.Visible = 1;
                app.DirectionDown.Visible =0 ;
            elseif moving == 2
                app.Lamp.Enable = 1;
                app.DirectionDown.Visible = 1;
                app.DirectionUp.Visible = 0;
            else
                app.Lamp.Enable = 'off';
                app.DirectionDown.Visible = 0;
                app.DirectionUp.Visible = 0;
            end

            if DoorOperation == 1
                pause(app.LM);
                %                 play(app.Floor_1);
            else
                %                 stop(app.Floor_1);
                resume(app.LM);
                app.FloorCounter = 0;

            end


        end

        function DetectObject(app)
            if app.ObjectDetected == 1
                app.chart.ObjectDetected = 1;
                app.TestText.Value = "1";
            else
                app.chart.ObjectDetected = 0;
                app.TestText.Value = "0";
            end
        end

        function Logic(app,NxFloor)
            app.chart.Req_Floor = NxFloor;
            app.ReqFloor = NxFloor;
        end

        function updateDoorStatus(app, Status, Cur_Floor, ArriveFloor)
            if ArriveFloor == 1
                if app.FloorCounter == 0
                    app.FloorCounter = 1;
                    playblocking(app.Floor);
                    if (round(Cur_Floor) == 1)
                        playblocking(app.AOne);
                    elseif round(Cur_Floor) == 2
                        playblocking(app.ATwo);
                    elseif round(Cur_Floor) == 3
                        playblocking(app.AThree);
                    elseif round(Cur_Floor) == 4
                        playblocking(app.AFour);
                    elseif round(Cur_Floor) == 5
                        playblocking(app.AFive);
                    elseif round(Cur_Floor) == 6
                        playblocking(app.ASix);
                    elseif round(Cur_Floor) == 7
                        playblocking(app.ASeven);
                    elseif round(Cur_Floor) == 8
                        playblocking(app.AEight);
                    end
                    disp("app.FloorCounter = " + string(app.FloorCounter))
                end
            end

            if Status == 0 %&& ObjectDetected == 0
                app.DoorStatusTextArea.Value = "Doors Opening";
                disp("Doors Opening")
                disp("app.Counter = "+ string(app.Counter))
                if app.Counter == 0
                    play(app.DoorsOp);
                    app.Counter = 1;
                    disp("here")
                end
            elseif Status == 1 %&& ObjectDetected == 0
                app.DoorStatusTextArea.Value = "Door Open";
                app.Counter = 0;
            elseif Status == 2 %&& ObjectDetected == 0
                app.DoorStatusTextArea.Value = "Door Closing";
                if app.Counter == 0
                    play(app.DoorsCl)
                    app.Counter = 1;
                end
            elseif Status == 3
                app.DoorStatusTextArea.Value = "Door Closed";
                app.Counter = 0;
            else
                app.DoorStatusTextArea.Value = "Error";
            end

            if Status == 2 || Status == 1 || Status == 0
                app.ObjectDetectionButton.Visible = 1;
            else
                app.ObjectDetectionButton.Visible = 0;
                app.ObjectDetectionButton.Text = "Object: No";
                app.ObjectDetectionButton.BackgroundColor = [1 0 0];
            end

        end

        function DoorOperationReq(app, DoorPush)
            app.chart.DoorPush = DoorPush;
            app.DoorPushTextArea.Value = string(DoorPush);
        end

        function Floor_request(app, Req_Floor)
            Logic(app,Req_Floor);
        end

        function Emergency(app,Emg)
            app.chart.Emg = Emg;
            disp("Hello")
            if Emg == 1
                pause(app.LM);
                playblocking(app.AEmg)
            else
                resume(app.LM);
            end
        end
        
        function CallDirecion(app, Direction)
            app.chart.Direction = Direction;
        end


        function liftCall(app, Velocity,Cur_Floor) % nextFloor changes from existing if redirection to be done
            global passengerMatrix % Adds to Passenger Matrix, also checks if empty
            passengerMatrix = [];

            global maxPassengers
            maxPassengers = 10;


            % Generates New User ID in the Passenger Matrix
            if Velocity > 0
                liftDirection = 1;
            elseif Velocity < 0
                liftDirection = -1;
            else
                liftDirection = 0;
            end

            Floor = app.ReqFloor;
            Direction = app.liftCallDirection;
            liftPosition = Cur_Floor;
            liftDestination = app.ReqFloor;


            avgFloor = 0;
            if Direction == 1%"Up"
                avgFloor = round(((10 - Floor)/2) + Floor);
            else
                avgFloor = round(Floor / 2);
            end
            %             disp("Hello\n")


            passenger = [double(Floor), now, avgFloor, 0, 0, Direction];
            %             disp(passenger)
            %             disp(string(app.ReqFloor))
            %             disp("_________________")


            if size(passengerMatrix,1) == 0
                passengerMatrix = passenger;
            else
                passengerMatrix = [passengerMatrix; passenger];
            end


            % Checks if request is en-route and that there is no urgent passenger

            overtimeFloor = overtimeCheck(app);

            nextFloor = 0;

            if double(overtimeFloor) ~= 0
                if liftDirection == 1 && overtimeFloor > liftPosition && overtimeFloor < liftDestination%"Up" && overtimeFloor > liftPosition && overtimeFloor < liftDestination
                    nextFloor = overtimeFloor;
                elseif liftDirection == -1 && overtimeFloor < liftPosition && overtimeFloor > liftDestination%"Down" && overtimeFloor < liftPosition && overtimeFloor > liftDestination
                    nextFloor = overtimeFloor;
                else
                    nextFloor = liftDestination;
                end
            else
                if liftDirection == 0%"None" % Lift is Stationary
                    nextFloor = Floor;
                elseif liftDirection == 1 && Floor > liftPosition && Direction == liftDirection && Floor < liftDestination%"Up" && Floor > liftPosition && Direction == liftDirection && Floor < liftDestination
                    nextFloor = Floor;
                elseif liftDirection == -1 && Floor < liftPosition && Direction == liftDirection && Floor > liftDestination%"Down" && Floor < liftPosition && Direction == liftDirection && Floor > liftDestination
                    nextFloor = Floor;
                else
                    nextFloor = liftDestination;
                end
            end

            % Checks if doing the re-route will cause too many passengers to be
            % picked up
            if nextFloor == Floor
                tempCounter = 0;
                currentPassengers = 0;
                for j = 1:size(passengerMatrix,1)
                    passengerMatrix(j,4)
                    if double(passengerMatrix(j,4)) ~= 0
                        currentPassengers = currentPassengers + 1;
                    end
                    if double(passengerMatrix(j,5)) == Floor
                        tempCounter = tempCounter - 1;
                    elseif double(passengerMatrix(j,1)) == Floor
                        tempCounter = tempCounter + 1;
                    end
                end
                if currentPassengers + tempCounter > maxPassengers
                    nextFloor = liftDestination;
                end
            end
            %             disp("nextFloor" + string(nextFloor))
            %             %             disp(string(Direction))
            %             disp("Hello\n")

            app.chart.nextFloor = nextFloor;
            app.chart.Req_Floor = nextFloor;
            %Check if enough time to slow down
        end

        function priorityFloor = overtimeCheck(app)
            % Goes through Pass.Mtrx and checks for overtime
            global passengerMatrix
            overtimePos = [];
            overtimeWait = [];
            overtimeType = [];
            for i = 1:size(passengerMatrix,1)
                if now - double(passengerMatrix(i,2)) > 45000 && double(passengerMatrix(i,4)) == 0
                    overtimePos(length(overtimePos) + 1) = i;
                    overtimeWait(length(overtimeWait) + 1) = now - double(passengerMatrix(i,2)) - 45000; %Target Times in Milliseconds
                    overtimeType(length(overtimePos) + 1) = "Wait";
                elseif now - double(passengerMatrix(i,4)) > 45000  && double(passengerMatrix(i,4)) ~= 0
                    overtimePos(length(overtimePos) + 1) = i;
                    overtimeWait(length(overtimeWait) + 1) = now - double(passengerMatrix(i,4)) - 45000;
                    overtimeType(length(overtimePos) + 1) = "Lift";
                elseif ((now - (double(passengerMatrix(i,4))))+(double(passengerMatrix(i,4))-double(passengerMatrix(i,2)))) > 60000  && double(passengerMatrix(i,4)) ~= 0
                    overtimePos(length(overtimePos) + 1) = i;
                    overtimeWait(length(overtimeWait) + 1) = ((now - (double(passengerMatrix(i,4))))+(double(passengerMatrix(i,4))-double(passengerMatrix(i,2)))) - 60000;
                    overtimeType(length(overtimePos) + 1) = "Lift";
                end
            end

            % Checks if overtime present, where to go first.

            if length(overtimeWait) > 0
                sortWaitTimes = sort(overtimeWait);
                priorityIndex = find(overtimeWait == sortWaitTimes(length(sortWaitTimes)));
                priorityFloor = passengerMatrix(overtimePos(priorityIndex),1);
            else
                priorityFloor = 0;
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, chart)
            app.chart = chart;               % access data in Stateflow chart
            assignin('base','app',app)      % access app from base workspaces
            app.Ext_User_Display.FontSize = 35;
            app.ObjectDetectionButton.Visible = 0;
            app.DirectionDown.Visible = 0;
            app.DirectionUp.Visible = 0;
            app.liftCallDirection  = 0;
            app.ReqFloor = 0;
            app.Counter = 0;
            app.FloorCounter = 1;

            %             Lift Music
            lift_music = pwd + "/Audio/trial_a3.mp3";
            [y, Lm] = audioread(lift_music);
            app.LM=audioplayer(y,Lm);
            play(app.LM);

            %             Alarm Bell
            alarm_bell = pwd + "/Audio/Alarm_bell.mp3";
            [y_ab, abs] = audioread(alarm_bell);
            app.AB = audioplayer(y_ab, abs);

            %             %             Arriving At Floor
            %             floor_1 = pwd + "/Audio/trial_a4.mp3";
            %             [f1, F1] = audioread(floor_1);
            %             app.Floor_1 = audioplayer(f1, F1);

            %             Doors Opening
            doors_op = pwd + "/Audio/Doors_Opening.mp3";
            [y_op, OP] = audioread(doors_op);
            app.DoorsOp = audioplayer(y_op,OP);

            %              Doors Closing
            doors_cl = pwd + "/Audio/Doors_Closing.mp3";
            [y_cl, CL] = audioread(doors_cl);
            app.DoorsCl = audioplayer(y_cl,CL);

            %             Floor
            floor = pwd + "/Audio/Floor.mp3";
            [y_f, F] = audioread(floor);
            app.Floor = audioplayer(y_f, F);

            %             One
            one = pwd + "/Audio/One.mp3";
            [y_1, O] = audioread(one);
            app.AOne = audioplayer(y_1, O);

            %             Two
            two = pwd + "/Audio/Two.mp3";
            [y_2, Tw] = audioread(two);
            app.ATwo = audioplayer(y_2, Tw);

            %             Three
            three = pwd + "/Audio/Three.mp3";
            [y_3, Thr] = audioread(three);
            app.AThree = audioplayer(y_3, Thr);

            %             Four
            four = pwd + "/Audio/Four.mp3";
            [y_4, Fou] = audioread(four);
            app.AFour = audioplayer(y_4, Fou);

            %             Five
            five = pwd + "/Audio/Five.mp3";
            [y_5, Fiv] = audioread(five);
            app.AFive = audioplayer(y_5, Fiv);

            %             Six
            six = pwd + "/Audio/Six.mp3";
            [y_6, Si] = audioread(six);
            app.ASix = audioplayer(y_6, Si);

            %             Seven
            seven = pwd + "/Audio/Seven.mp3";
            [y_7, Se] = audioread(seven);
            app.ASeven = audioplayer(y_7, Se);

            %             Eight
            eight = pwd + "/Audio/Eight.mp3";
            [y_8, Ei] = audioread(eight);
            app.AEight = audioplayer(y_8, Ei);

            %             Emergency
            emg = pwd + "/Audio/Emergency.mp3";
            [y_e, Em] =  audioread(emg);
            app.AEmg = audioplayer(y_e, Em);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            set_param('Simulink_Model','SimulationCommand', 'stop');
        end

        % Callback function
        function ButtonPushed(app, event)
            app.TextArea.Value = string(event.Source.Text);
        end

        % Button pushed function: Button_2
        function One(app, event)
            Floor_request(app, 1)
        end

        % Button pushed function: Button_3
        function Two(app, event)
            Floor_request(app, 2)
        end

        % Button pushed function: Button_4
        function Three(app, event)
            Floor_request(app, 3)
        end

        % Button pushed function: Button_5
        function Four(app, event)
            Floor_request(app, 4)
        end

        % Button pushed function: Button_6
        function Five(app, event)
            Floor_request(app, 5)
        end

        % Button pushed function: Button_7
        function Six(app, event)
            Floor_request(app, 6)
        end

        % Button pushed function: Button_8
        function Seven(app, event)
            Floor_request(app, 7)
        end

        % Button pushed function: Button_9
        function Eight(app, event)
            Floor_request(app, 8)
        end

        % Button pushed function: STOPButton
        function STOP(app, event)
            Emergency(app, 1);
        end

        % Button pushed function: NormalOperationButton
        function GO(app, event)
            Emergency(app, 0);
        end

        % Button pushed function: Door_Close
        function DoorClose(app, event)
            DoorOperationReq(app,1);
        end

        % Button pushed function: Door_Open
        function DoorOpen(app, event)
            DoorOperationReq(app,0);
        end

        % Value changed function: FloorSpinner
        function FloorSpinnerValueChanged(app, event)
            app.Lamp.Enable = 0;
            if app.FloorSpinner.Value == 1
                app.DOWNButton.Visible = 0;
                app.DOWNButton.Enable = 0;
            elseif app.FloorSpinner.Value == 8
                app.UPButton.Visible = 0;
                app.UPButton.Enable = 0;
            else
                app.DOWNButton.Visible = 1;
                app.DOWNButton.Enable = 1;
                app.UPButton.Visible = 1;
                app.UPButton.Enable = 1;
            end
        end

        % Button pushed function: DOWNButton
        function DOWNButtonPushed(app, event)
            %             app.UpDownReq(1,end+1) = -1*app.FloorSpinner.Value;
            %             app.UpDownReq(2,end) = app.NoPassengersSpinner.Value;
            %             assignin('base','UpDownReq',app.UpDownReq);
            %             app.Lamp.Enable = 1;
            CallDirection(app, -1);
            app.liftCallDirection = -1;
        end

        % Button pushed function: UPButton
        function UPButtonPushed(app, event)
            %             app.FloorReached()
            %             assignin('base','RqFloor',app.RqFloor);
            CallDirecion(app,1);
            app.liftCallDirection = 1;
        end

        % Button pushed function: ObjectDetectionButton
        function ObjectDetectionToggle(app, event)
            if isequal(event.Source.Text, "Object: No")
                app.ObjectDetectionButton.Text = "Object: Yes";
                app.ObjectDetectionButton.BackgroundColor = [0 1 0];
                stop(app.LM);
                app.ObjectDetected = 1;
            elseif isequal(event.Source.Text, "Object: Yes")
                app.ObjectDetectionButton.Text = "Object: No";
                app.ObjectDetectionButton.BackgroundColor = [1 0 0];
                app.ObjectDetected = 0;
            end
        end

        % Button pushed function: StartButton
        function StartAudio(app, event)
            try
                play(app.LM)
            catch ME
                uiwait(msgbox('Could not open that file with audioread'));
            end
        end

        % Button pushed function: StopButton
        function StopAudio(app, event)
            try
                stop(app.LM)
            catch ME
                uiwait(msgbox('Could not open that file with audioread'));
            end
        end

        % Button pushed function: pauseButton
        function PauseAudio(app, event)
            try
                pause(app.LM)
            catch ME
                uiwait(msgbox('Could not open that file with audioread'));
            end
        end

        % Button pushed function: resumeButton
        function ResumeAudio(app, event)
            try
                resume(app.PO)
            catch ME
                uiwait(msgbox('Could not open that file with audioread'));
            end
        end

        % Button pushed function: AlarmStartButton
        function AlarmBell_Start(app, event)
            try
                stop(app.Floor_1);
                stop(app.LM);
                play(app.AB);
            catch ME
                uiwait(msgbox('Could not open that file with audioread'));
            end
        end

        % Button pushed function: AlarmStopButton
        function AlarmBell_Stop(app, event)
            try
                stop(app.AB);
                play(app.LM);
            catch ME
                uiwait(msgbox('Could not open that file with audioread'));
            end
        end

        % Button pushed function: GButton
        function GroundFloor(app, event)
            Floor_request(app, 0);
        end

        % Button pushed function: P1Button
        function ParkingFloor(app, event)
            Floor_request(app, -1);
        end

        % Button pushed function: DirectionUp
        function ExtDisDirection(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FloorGauge = uigauge(app.UIFigure, 'circular');
            app.FloorGauge.Limits = [0 8];
            app.FloorGauge.MajorTicks = [0 1 2 3 4 5 6 7 8];
            app.FloorGauge.MinorTicks = [];
            app.CurrentFloorEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentFloorEditFieldLabel.Text = 'Current Floor';
            app.CurrentFloorEditField = uieditfield(app.UIFigure, 'text');
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @One, true);
            app.Button_2.Text = '1';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Two, true);
            app.Button_3.Text = '2';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Three, true);
            app.Button_4.Text = '3';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Four, true);
            app.Button_5.Text = '4';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Five, true);
            app.Button_6.Text = '5';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Six, true);
            app.Button_7.Text = '6';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Seven, true);
            app.Button_8.Text = '7';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Eight, true);
            app.Button_9.Text = '8';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOP, true);
            app.STOPButton.Text = 'STOP';
            app.MOVINGButton = uibutton(app.UIFigure, 'push');
            app.MOVINGButton.Text = 'MOVING';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp.Enable = 'off';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.NormalOperationButton = uibutton(app.UIFigure, 'push');
            app.NormalOperationButton.ButtonPushedFcn = createCallbackFcn(app, @GO, true);
            app.NormalOperationButton.Text = 'Normal Operation';
            app.UPButton = uibutton(app.UIFigure, 'push');
            app.UPButton.ButtonPushedFcn = createCallbackFcn(app, @UPButtonPushed, true);
            app.UPButton.Text = 'UP';
            app.DOWNButton = uibutton(app.UIFigure, 'push');
            app.DOWNButton.ButtonPushedFcn = createCallbackFcn(app, @DOWNButtonPushed, true);
            app.DOWNButton.Enable = 'off';
            app.DOWNButton.Visible = 'off';
            app.DOWNButton.Text = 'DOWN';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_2.Enable = 'off';
            app.NoPassengersSpinnerLabel = uilabel(app.UIFigure);
            app.NoPassengersSpinnerLabel.Text = 'No. Passengers';
            app.NoPassengersSpinner = uispinner(app.UIFigure);
            app.NoPassengersSpinner.Limits = [1 20];
            app.NoPassengersSpinner.Value = 1;
            app.FloorSpinnerLabel = uilabel(app.UIFigure);
            app.FloorSpinnerLabel.Text = 'Floor';
            app.FloorSpinner = uispinner(app.UIFigure);
            app.FloorSpinner.Limits = [1 8];
            app.FloorSpinner.ValueChangedFcn = createCallbackFcn(app, @FloorSpinnerValueChanged, true);
            app.FloorSpinner.Value = 1;
            app.Door_Close = uibutton(app.UIFigure, 'push');
            app.Door_Close.ButtonPushedFcn = createCallbackFcn(app, @DoorClose, true);
            app.Door_Close.Text = '><';
            app.Door_Open = uibutton(app.UIFigure, 'push');
            app.Door_Open.ButtonPushedFcn = createCallbackFcn(app, @DoorOpen, true);
            app.Door_Open.Text = '<>';
            app.DoorStatusTextAreaLabel = uilabel(app.UIFigure);
            app.DoorStatusTextAreaLabel.Text = 'Door Status';
            app.DoorStatusTextArea = uitextarea(app.UIFigure);
            app.Ext_User_Display = uieditfield(app.UIFigure, 'text');
            app.ObjectDetectionButton = uibutton(app.UIFigure, 'push');
            app.ObjectDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @ObjectDetectionToggle, true);
            app.ObjectDetectionButton.Text = 'Object: No';
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'A button will appear below when users are able to leave/enter the lift. '; 'This button simulates users passing through the doors and activating the ObjectDetection function. '};
            app.TestTextLabel = uilabel(app.UIFigure);
            app.TestTextLabel.Text = 'TestText';
            app.TestText = uieditfield(app.UIFigure, 'text');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartAudio, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopAudio, true);
            app.StopButton.Text = 'Stop';
            app.pauseButton = uibutton(app.UIFigure, 'push');
            app.pauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseAudio, true);
            app.pauseButton.Text = 'pause';
            app.resumeButton = uibutton(app.UIFigure, 'push');
            app.resumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeAudio, true);
            app.resumeButton.Text = 'resume';
            app.AlarmStartButton = uibutton(app.UIFigure, 'push');
            app.AlarmStartButton.ButtonPushedFcn = createCallbackFcn(app, @AlarmBell_Start, true);
            app.AlarmStartButton.Text = 'Alarm Start';
            app.AlarmStopButton = uibutton(app.UIFigure, 'push');
            app.AlarmStopButton.ButtonPushedFcn = createCallbackFcn(app, @AlarmBell_Stop, true);
            app.AlarmStopButton.Text = 'Alarm Stop';
            app.GButton = uibutton(app.UIFigure, 'push');
            app.GButton.ButtonPushedFcn = createCallbackFcn(app, @GroundFloor, true);
            app.GButton.Text = 'G';
            app.P1Button = uibutton(app.UIFigure, 'push');
            app.P1Button.ButtonPushedFcn = createCallbackFcn(app, @ParkingFloor, true);
            app.P1Button.Text = 'P1';
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.UIFigure, 'semicircular');
            app.Gauge.Limits = [-1 8];
            app.Gauge.MajorTicks = [-1 0 1 2 3 4 5 6 7 8];
            app.DirectionUp = uibutton(app.UIFigure, 'push');
            app.DirectionUp.ButtonPushedFcn = createCallbackFcn(app, @ExtDisDirection, true);
            app.DirectionUp.Text = '↑';
            app.DirectionDown = uibutton(app.UIFigure, 'push');
            app.DirectionDown.Text = '↓';
            app.TextArea_4 = uitextarea(app.UIFigure);
            app.TextArea_4.Value = {'G and P1 think about logic in there.'};
            app.TextArea_5 = uitextarea(app.UIFigure);
            app.TextArea_5.Value = {'Assumptions, list starts at floor 0. '};
            app.DoorPushTextAreaLabel = uilabel(app.UIFigure);
            app.DoorPushTextAreaLabel.Text = 'DoorPush';
            app.DoorPushTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppWSimulink(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end