classdef App_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Lamp_2                    matlab.ui.control.Lamp
        EditField                 matlab.ui.control.EditField
        STOPButton                matlab.ui.control.Button
        FloorSpinner              matlab.ui.control.Spinner
        FloorSpinnerLabel         matlab.ui.control.Label
        Lamp                      matlab.ui.control.Lamp
        DOWNButton                matlab.ui.control.Button
        UPButton                  matlab.ui.control.Button
        TextArea                  matlab.ui.control.TextArea
        NoPassengersSpinner       matlab.ui.control.Spinner
        NoPassengersSpinnerLabel  matlab.ui.control.Label
        MOVINGButton              matlab.ui.control.Button
        Button_8                  matlab.ui.control.Button
        Button_7                  matlab.ui.control.Button
        Button_6                  matlab.ui.control.Button
        Button_5                  matlab.ui.control.Button
        Button_4                  matlab.ui.control.Button
        Button_3                  matlab.ui.control.Button
        FloorGauge                matlab.ui.control.Gauge
        Button_2                  matlab.ui.control.Button
        Button_1                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        RqFloor = '';
        UpDownReq;
        StFlwChrt;
        CurFloor;
        Emg;
    end
    
    methods (Access = private)
        
        function FloorReached(app)
            app.RqFloor = app.RqFloor(2:end);
            disp(app.RqFloor)
        end


        function Logic(app)
           NxtFloor = str2double(app.RqFloor(end));
           NextFloor(app, NxtFloor,1)
           
        end
                
        
        function NextFloor(app, NxtFloor, LiftCall)
            app.StFlwChrt.Req_Floor = NxtFloor;
            app.StFlwChrt.LiftCall = LiftCall;
            while app.StFlwChrt.Cur_Floor~=app.StFlwChrt.Req_Floor
                step(app.StFlwChrt);
                disp(app.StFlwChrt);
                pause(0.15)
                app.FloorGauge.Value = app.StFlwChrt.Cur_Floor;
                app.EditField.Value = string(app.StFlwChrt.Cur_Floor);
                if app.StFlwChrt.Moving == 1
                    app.Lamp_2.Enable = 'on';
                else
                    app.Lamp_2.Enable = 'off';
                end
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
            app.StFlwChrt = chart('Cur_Floor', 1);
            app.StFlwChrt.Cur_Floor = 1;
            app.EditField.FontSize = 35;
        end

        % Button pushed function: Button_1, Button_2, Button_3, 
        % Button_4, Button_5, Button_6, Button_7, Button_8
        function FloorButton_Pushed(app, event)
            floor = event.Source.Text;
            app.RqFloor = append(app.RqFloor,floor);
            disp(app.RqFloor(end))
            assignin('base','RqFloor',app.RqFloor);
           
            Logic(app)
            
        end

        % Button pushed function: MOVINGButton
        function MOVINGButtonPushed(app, event)
            app.FloorReached()
            assignin('base','RqFloor',app.RqFloor);
        end

        % Button pushed function: UPButton
        function UPButtonPushed(app, event)
            app.UpDownReq(1,end+1) = app.FloorSpinner.Value;
            app.UpDownReq(2,end) = app.NoPassengersSpinner.Value;
            assignin('base','UpDownReq',app.UpDownReq);
            app.Lamp.Enable = 1;
        end

        % Button pushed function: DOWNButton
        function DOWNButtonPushed(app, event)
            app.UpDownReq(1,end+1) = -1*app.FloorSpinner.Value;
            app.UpDownReq(2,end) = app.NoPassengersSpinner.Value;
            assignin('base','UpDownReq',app.UpDownReq);
            app.Lamp.Enable = 1;
        end

        % Value changed function: FloorSpinner
        function FloorSpinnerValueChanged(app, event)
            app.Lamp.Enable = 0;
            if app.FloorSpinner.Value == 1
                app.DOWNButton.Visible = 0;
                app.DOWNButton.Enable = 0;
            elseif app.FloorSpinner.Value == 8
                app.UPButton.Visible = 0;
                app.UPButton.Enable = 0;
            else
                app.DOWNButton.Visible = 1;
                app.DOWNButton.Enable = 1;
                app.UPButton.Visible = 1;
                app.UPButton.Enable = 1;
            end
            
        end

        % Value changed function: EditField
        function Display(app, event)
            
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.Emg = 1;
            app.StFlwChrt.Emg = 1;
            app.StFlwChrt.Moving = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button_1 = uibutton(app.UIFigure, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @FloorButton_Pushed, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @FloorButton_Pushed, true);
            app.Button_2.Text = '2';
            app.FloorGauge = uigauge(app.UIFigure, 'circular');
            app.FloorGauge.Limits = [1 8];
            app.FloorGauge.MajorTicks = [0 1 2 3 4 5 6 7 8];
            app.FloorGauge.MinorTicks = [];
            app.FloorGauge.Value = 1;
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @FloorButton_Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @FloorButton_Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @FloorButton_Pushed, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @FloorButton_Pushed, true);
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @FloorButton_Pushed, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @FloorButton_Pushed, true);
            app.Button_8.Text = '8';
            app.MOVINGButton = uibutton(app.UIFigure, 'push');
            app.MOVINGButton.ButtonPushedFcn = createCallbackFcn(app, @MOVINGButtonPushed, true);
            app.MOVINGButton.Text = 'MOVING';
            app.NoPassengersSpinnerLabel = uilabel(app.UIFigure);
            app.NoPassengersSpinnerLabel.Text = 'No. Passengers';
            app.NoPassengersSpinner = uispinner(app.UIFigure);
            app.NoPassengersSpinner.Limits = [1 20];
            app.NoPassengersSpinner.Value = 1;
            app.TextArea = uitextarea(app.UIFigure);
            app.UPButton = uibutton(app.UIFigure, 'push');
            app.UPButton.ButtonPushedFcn = createCallbackFcn(app, @UPButtonPushed, true);
            app.UPButton.Text = 'UP';
            app.DOWNButton = uibutton(app.UIFigure, 'push');
            app.DOWNButton.ButtonPushedFcn = createCallbackFcn(app, @DOWNButtonPushed, true);
            app.DOWNButton.Enable = 'off';
            app.DOWNButton.Visible = 'off';
            app.DOWNButton.Text = 'DOWN';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp.Enable = 'off';
            app.FloorSpinnerLabel = uilabel(app.UIFigure);
            app.FloorSpinnerLabel.Text = 'Floor';
            app.FloorSpinner = uispinner(app.UIFigure);
            app.FloorSpinner.Limits = [1 8];
            app.FloorSpinner.ValueChangedFcn = createCallbackFcn(app, @FloorSpinnerValueChanged, true);
            app.FloorSpinner.Value = 1;
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @Display, true);
            app.EditField.Visible = 'off';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_2.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end