classdef Copy_of_App_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        STOPButton                matlab.ui.control.Button
        FloorSpinner              matlab.ui.control.Spinner
        FloorSpinnerLabel         matlab.ui.control.Label
        Lamp                      matlab.ui.control.Lamp
        DOWNButton                matlab.ui.control.Button
        UPButton                  matlab.ui.control.Button
        TextArea                  matlab.ui.control.TextArea
        NoPassengersSpinner       matlab.ui.control.Spinner
        NoPassengersSpinnerLabel  matlab.ui.control.Label
        MOVINGButton              matlab.ui.control.Button
        Button_8                  matlab.ui.control.Button
        Button_7                  matlab.ui.control.Button
        Button_6                  matlab.ui.control.Button
        Button_5                  matlab.ui.control.Button
        Button_4                  matlab.ui.control.Button
        Button_3                  matlab.ui.control.Button
        FloorGauge                matlab.ui.control.Gauge
        Button_2                  matlab.ui.control.Button
        Button_1                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        RqFloor = '';
        UpDownReq;
        ChartLogic;
        NxFloor;
        CurFloor
    end
    
    methods (Access = private)
        
        function FloorReached(app)
            app.RqFloor = app.RqFloor(2:end);
            disp(app.RqFloor)
        end
        
        function Logic(app)
%             app.NxFloor = 2;
            app.CurFloor = 0;
            NextStop(app)
        end
        
        function NextStop(app)
            app.ChartLogic.Cur_Floor = 2;
            app.ChartLogic.Req_Floor = app.NxFloor;
            while app.ChartLogic.Cur_Floor~=app.ChartLogic.Req_Floor
                step(app.ChartLogic);
                disp(app.ChartLogic);
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ChartLogic = chart('Cur_Floor', 0);
            disp(app.ChartLogic)
        end

        % Button pushed function: Button_1, Button_2, Button_3, 
        % Button_4, Button_5, Button_6, Button_7, Button_8
        function Button_1Pushed(app, event)
            floor = event.Source.Text;
            app.RqFloor = append(app.RqFloor,floor);
            disp(app.RqFloor(end))
            assignin('base','RqFloor',app.RqFloor);
            app.FloorGauge.Value = eval(floor);
            app.NxFloor = eval(event.Source.Text);
%             disp(app.NxFloor)
            Logic(app)
        end

        % Callback function
        function Button_2Pushed(app, event)
            floor = '2';
            app.RqFloor = append(app.RqFloor,floor);
            disp(app.RqFloor(end))
            assignin('base','RqFloor',app.RqFloor);
        end

        % Callback function
        function Button_3Pushed(app, event)
            floor = '3';
            app.RqFloor = append(app.RqFloor,floor);
            disp(app.RqFloor(end))
            assignin('base','RqFloor',app.RqFloor);

        end

        % Callback function
        function Button_4Pushed(app, event)
            floor = '4';
            app.RqFloor = append(app.RqFloor,floor);
            disp(app.RqFloor(end))
            assignin('base','RqFloor',app.RqFloor);
        end

        % Callback function
        function Button_5Pushed(app, event)
            floor = '5';
            app.RqFloor = append(app.RqFloor,floor);
            disp(app.RqFloor(end))
            assignin('base','RqFloor',app.RqFloor);
        end

        % Callback function
        function Button_6Pushed(app, event)
            floor = '6';
            app.RqFloor = append(app.RqFloor,floor);
            disp(app.RqFloor(end))
            assignin('base','RqFloor',app.RqFloor);
        end

        % Callback function
        function Button_7Pushed(app, event)
            floor = '7';
            app.RqFloor = append(app.RqFloor,floor);
            disp(app.RqFloor(end))
            assignin('base','RqFloor',app.RqFloor);
        end

        % Callback function
        function Button_8Pushed(app, event)
            floor = '8';
            app.RqFloor = append(app.RqFloor,floor);
            disp(app.RqFloor(end))
            assignin('base','RqFloor',app.RqFloor);
        end

        % Button pushed function: MOVINGButton
        function MOVINGButtonPushed(app, event)
            app.FloorReached()
            assignin('base','RqFloor',app.RqFloor);
        end

        % Button pushed function: UPButton
        function UPButtonPushed(app, event)
            app.UpDownReq(1,end+1) = app.FloorSpinner.Value;
            app.UpDownReq(2,end) = app.NoPassengersSpinner.Value;
            assignin('base','UpDownReq',app.UpDownReq);
            app.Lamp.Enable = 1;
        end

        % Button pushed function: DOWNButton
        function DOWNButtonPushed(app, event)
            app.UpDownReq(1,end+1) = -1*app.FloorSpinner.Value;
            app.UpDownReq(2,end) = app.NoPassengersSpinner.Value;
            assignin('base','UpDownReq',app.UpDownReq);
            app.Lamp.Enable = 1;
        end

        % Value changed function: FloorSpinner
        function FloorSpinnerValueChanged(app, event)
            app.Lamp.Enable = 0;
            if app.FloorSpinner.Value == 1
                app.DOWNButton.Visible = 0;
                app.DOWNButton.Enable = 0;
            elseif app.FloorSpinner.Value == 8
                app.UPButton.Visible = 0;
                app.UPButton.Enable = 0;
            else
                app.DOWNButton.Visible = 1;
                app.DOWNButton.Enable = 1;
                app.UPButton.Visible = 1;
                app.UPButton.Enable = 1;
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            delete(app.ChartLogic)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Button_1 = uibutton(app.UIFigure, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_2.Text = '2';
            app.FloorGauge = uigauge(app.UIFigure, 'circular');
            app.FloorGauge.Limits = [1 8];
            app.FloorGauge.MajorTicks = [0 1 2 3 4 5 6 7 8];
            app.FloorGauge.MinorTicks = [];
            app.FloorGauge.Value = 1;
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_8.Text = '8';
            app.MOVINGButton = uibutton(app.UIFigure, 'push');
            app.MOVINGButton.ButtonPushedFcn = createCallbackFcn(app, @MOVINGButtonPushed, true);
            app.MOVINGButton.Text = 'MOVING';
            app.NoPassengersSpinnerLabel = uilabel(app.UIFigure);
            app.NoPassengersSpinnerLabel.Text = 'No. Passengers';
            app.NoPassengersSpinner = uispinner(app.UIFigure);
            app.NoPassengersSpinner.Limits = [1 20];
            app.NoPassengersSpinner.Value = 1;
            app.TextArea = uitextarea(app.UIFigure);
            app.UPButton = uibutton(app.UIFigure, 'push');
            app.UPButton.ButtonPushedFcn = createCallbackFcn(app, @UPButtonPushed, true);
            app.UPButton.Text = 'UP';
            app.DOWNButton = uibutton(app.UIFigure, 'push');
            app.DOWNButton.ButtonPushedFcn = createCallbackFcn(app, @DOWNButtonPushed, true);
            app.DOWNButton.Enable = 'off';
            app.DOWNButton.Visible = 'off';
            app.DOWNButton.Text = 'DOWN';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp.Enable = 'off';
            app.FloorSpinnerLabel = uilabel(app.UIFigure);
            app.FloorSpinnerLabel.Text = 'Floor';
            app.FloorSpinner = uispinner(app.UIFigure);
            app.FloorSpinner.Limits = [1 8];
            app.FloorSpinner.ValueChangedFcn = createCallbackFcn(app, @FloorSpinnerValueChanged, true);
            app.FloorSpinner.Value = 1;
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.Text = 'STOP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Copy_of_App_interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end