classdef AppWSimulink < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TextArea_8                   matlab.ui.control.TextArea
        TextArea_7                   matlab.ui.control.TextArea
        ResetButton                  matlab.ui.control.Button
        OperatorsDisplayLabel        matlab.ui.control.Label
        ExternalDisplayLabel         matlab.ui.control.Label
        InternalDisplayLabel         matlab.ui.control.Label
        ErrorMessageEditField        matlab.ui.control.EditField
        ErrorMessageEditFieldLabel   matlab.ui.control.Label
        ManualOverideSwitch          matlab.ui.control.ToggleSwitch
        ManualOverideSwitchLabel     matlab.ui.control.Label
        StoppedStatusEditField       matlab.ui.control.EditField
        StoppedStatusEditFieldLabel  matlab.ui.control.Label
        AccelerationEditField        matlab.ui.control.NumericEditField
        AccelerationEditFieldLabel   matlab.ui.control.Label
        VelocityEditField            matlab.ui.control.NumericEditField
        VelocityEditFieldLabel       matlab.ui.control.Label
        PositionEditField            matlab.ui.control.NumericEditField
        PositionEditFieldLabel       matlab.ui.control.Label
        FaultStateLamp               matlab.ui.control.Lamp
        FaultStateLampLabel          matlab.ui.control.Label
        DoorsinOperationsLamp        matlab.ui.control.Lamp
        DoorsinOperationsLampLabel   matlab.ui.control.Label
        NormalOperationLamp          matlab.ui.control.Lamp
        NormalOperationLampLabel     matlab.ui.control.Label
        Slider                       matlab.ui.control.Slider
        TextArea_6                   matlab.ui.control.TextArea
        DirectionDown                matlab.ui.control.Button
        DirectionUp                  matlab.ui.control.Button
        Gauge                        matlab.ui.control.SemicircularGauge
        AlarmStopButton              matlab.ui.control.Button
        AlarmStartButton             matlab.ui.control.Button
        TestText                     matlab.ui.control.EditField
        RequestedFloorLabel          matlab.ui.control.Label
        TextArea_3                   matlab.ui.control.TextArea
        ObjectDetectionButton        matlab.ui.control.Button
        Ext_User_Display             matlab.ui.control.EditField
        DoorStatusTextArea           matlab.ui.control.TextArea
        DoorStatusTextAreaLabel      matlab.ui.control.Label
        Door_Open                    matlab.ui.control.Button
        Door_Close                   matlab.ui.control.Button
        FloorSpinner                 matlab.ui.control.Spinner
        FloorSpinnerLabel            matlab.ui.control.Label
        Lamp_2                       matlab.ui.control.Lamp
        DOWNButton                   matlab.ui.control.Button
        UPButton                     matlab.ui.control.Button
        NormalOperationButton        matlab.ui.control.Button
        TextArea                     matlab.ui.control.TextArea
        Lamp                         matlab.ui.control.Lamp
        MOVINGButton                 matlab.ui.control.Button
        STOPButton                   matlab.ui.control.Button
        Button_9                     matlab.ui.control.Button
        Button_8                     matlab.ui.control.Button
        Button_7                     matlab.ui.control.Button
        Button_6                     matlab.ui.control.Button
        Button_5                     matlab.ui.control.Button
        Button_4                     matlab.ui.control.Button
        Button_3                     matlab.ui.control.Button
        Button_2                     matlab.ui.control.Button
        CurrentFloorEditField        matlab.ui.control.EditField
        CurrentFloorEditFieldLabel   matlab.ui.control.Label
    end


    properties (Access = private)
        chart % Description
        ObjectDetected;
        ReqFloor;
        liftCallDirection;
        Counter;
        FloorCounter;
        nextFloor;
        liftButtons;
        Q;
        reenter;
        Emg;
        FloorRecord;
        forPresentation;
        Status;
        enteredOnce;

        %       Audio Properties
        LM; % Lift_music
        Floor_1; % First floor
        AB;     % alarm bell
        DoorsOp; % Doors Opening
        DoorsCl; % Doors Closing
        Floor; % Floor
        AOne; % One
        ATwo; AThree; AFour; AFive; ASix; ASeven; AEight;
        AEmg;
    end

    methods (Access = public)

        function startingValues(app)
            app.Ext_User_Display.FontSize = 35;
            app.ObjectDetectionButton.Visible = 0;
            app.DirectionDown.Visible = 0;
            app.DirectionUp.Visible = 0;
            app.liftCallDirection  = 0;
            app.ReqFloor = 0;
            app.Counter = 0;
            app.FloorCounter = 1;
            app.nextFloor = 1;
            app.FloorSpinner.Value = 1;
            app.liftButtons = [];
            app.Q = [];
            app.FloorRecord = [];
            app.reenter = 0;
            app.Emg = 0;
            app.FaultStateLamp.Enable = 0;
            app.DoorsinOperationsLamp.Enable =0;
            app.forPresentation = 0;
            app.enteredOnce = 0;
            global passengerMatrix
            passengerMatrix = [];

            global maxPassengers
            maxPassengers = 5;

            global liftFloors
            liftFloors = 8;

            if app.forPresentation == 1
                app.chart.Req_Floor = 1;
                app.chart.Emg = 0;
                app.chart.DoorPush = -2;
                app.chart.ObjectDetected =0;
                app.chart.ifRequested = 1;
                app.chart.counter = 0;
                app.chart.Passengers = 0;
            end
        end

        function updateCounter(app, Cur_Floor, moving, DoorOperation)
            app.CurrentFloorEditField.Value = string(Cur_Floor);
            app.Gauge.Value = Cur_Floor;
            app.Ext_User_Display.Value = string(round(Cur_Floor));
            app.PositionEditField.Value = Cur_Floor*3;
            app.VelocityEditField.Value = app.chart.Velocity;
            app.TestText.Value = string(app.chart.Req_Floor);
            app.StoppedStatusEditField.Value = string(app.chart.liftStatus);
            app.AccelerationEditField.Value = app.chart.Acceleration;

            if moving == 1 % when moving the light should be on
                app.Lamp.Enable = 'on';
                app.DirectionUp.Visible = 1;
                app.DirectionDown.Visible =0;
                app.reenter = 0;
            elseif moving == 2
                app.Lamp.Enable = 1;
                app.DirectionDown.Visible = 1;
                app.DirectionUp.Visible = 0;
            else
                app.Lamp.Enable = 'off';
                app.DirectionDown.Visible = 0;
                app.DirectionUp.Visible = 0;
            end


        end

        function DetectObject(app)
            if app.ObjectDetected == 1
                app.chart.ObjectDetected = 1;
                %                 app.TestText.Value = "1";
            else
                app.chart.ObjectDetected = 0;
                %                 app.TestText.Value = "0";
            end
        end

        function updateDoorStatus(app, Status, Cur_Floor, ArriveFloor)
            app.Status = Status;
            if app.chart.DoorOperation == 1
                pause(app.LM);
                app.DoorsinOperationsLamp.Enable = 1;
                %                 play(app.Floor_1);
            elseif app.chart.DoorOperation == 0
                %                 stop(app.Floor_1);
                resume(app.LM);
                app.FloorCounter = 0;
                app.DoorsinOperationsLamp.Enable = 0;
            end
            if ArriveFloor == 1
                if app.FloorCounter == 0
                    app.FloorCounter = 1;
                    playblocking(app.Floor);
                    if (round(Cur_Floor) == 1)
                        playblocking(app.AOne);
                    elseif round(Cur_Floor) == 2
                        playblocking(app.ATwo);
                    elseif round(Cur_Floor) == 3
                        playblocking(app.AThree);
                    elseif round(Cur_Floor) == 4
                        playblocking(app.AFour);
                    elseif round(Cur_Floor) == 5
                        playblocking(app.AFive);
                    elseif round(Cur_Floor) == 6
                        playblocking(app.ASix);
                    elseif round(Cur_Floor) == 7
                        playblocking(app.ASeven);
                    elseif round(Cur_Floor) == 8
                        playblocking(app.AEight);
                    end
                    disp("app.FloorCounter = " + string(app.FloorCounter))
                end
            end

            if app.Status == 0 %&& ObjectDetected == 0
                app.DoorStatusTextArea.Value = "Doors Opening";
                disp("Doors Opening")
                disp("app.Counter = "+ string(app.Counter))
                if app.Counter == 0
                    play(app.DoorsOp);
                    app.Counter = 1;
                    %                     disp("here")
                end
            elseif app.Status == 1 %&& ObjectDetected == 0
                app.DoorStatusTextArea.Value = "Door Open";
                app.Counter = 0;
            elseif app.Status == 2 %&& ObjectDetected == 0
                app.DoorStatusTextArea.Value = "Door Closing";
                if app.Counter == 0
                    play(app.DoorsCl)
                    app.Counter = 1;
                end
            elseif app.Status == 3
                app.DoorStatusTextArea.Value = "Door Closed";
                app.Counter = 0;
            else
                app.DoorStatusTextArea.Value = "Error";
            end

            if app.Status == 2 || app.Status == 1 || app.Status == 0
                app.ObjectDetectionButton.Visible = 1;
            else
                app.ObjectDetectionButton.Visible = 0;
                app.ObjectDetectionButton.Text = "Object: No";
                app.ObjectDetectionButton.BackgroundColor = [1 0 0];
            end

        end

        function DoorOperationReq(app, DoorPush)
            app.chart.DoorPush = DoorPush;
        end

        function Floor_request(app, Req_Floor)
            app.liftButtons = [app.liftButtons, Req_Floor];
            disp("\napp.liftButtons = " + app.liftButtons)

            app.chart.ifRequested = app.chart.ifRequested + 1;
        end

        function Queu(app)
            disp(app.Q)
            if ~isempty(app.Q)
                if abs(app.chart.Cur_Floor -app.Q(1))>0.01
                    app.chart.Req_Floor = app.Q(1);
                elseif abs(app.chart.Req_Floor-app.chart.Cur_Floor)<=0.01
                    if size(app.Q)<1
                        app.Q = [];
                    else
                        app.Q = app.Q(2:end);
                        app.liftButtons = app.liftButtons(2:end);
                    end
                end
            else
                app.chart.Req_Floor = round(app.chart.Cur_Floor);
            end
        end

        function Emergency(app,Emg)
            app.chart.Emg = Emg;
            app.Emg = Emg;
            if Emg ~=0
                pause(app.LM);
                playblocking(app.AEmg)
            else
                if app.chart.DoorOperation == 0
                    resume(app.LM);
                end
            end

        end

        function updateError(app,ErrorMsg)
            Log = 0;
            ErrorValue = ErrorMsg;
            if ErrorMsg ~= 0
                app.NormalOperationLamp.Enable = 0;
                app.FaultStateLamp.Enable = 1;
                Log = 1;
            else
                app.NormalOperationLamp.Enable = 1;
                app.FaultStateLamp.Enable = 0;
            end

            if ErrorMsg == 1
                ErrorMsg = "User Pressed STOP";
            elseif ErrorMsg == 2
                ErrorMsg = "Position Sensor Failure";
            elseif ErrorMsg == 3
                ErrorMsg = "Weight Sensor Failure";
            elseif ErrorMsg == 4
                ErrorMsg = "Fire Deteced";
            elseif ErrorMsg == 5
                ErrorMsg = "Smoke Detected";
            elseif ErrorMsg == 6
                ErrorMsg = "Manual Overide";
            else
                ErrorMsg = "No Issues";
            end
            app.ErrorMessageEditField.Value = ErrorMsg;

            if Log == 1
                if app.enteredOnce ~= ErrorValue
                    app.enteredOnce = ErrorValue;

                    time = clock;
                    address = pwd + "/Logs/" + date;
                    name = time(4) +":" + time(5)+":" + round(time(6)) + ".log";
                    %             Msg = 'Test Log';
                    mkdir(address)
                    Dir = address+"/" +name;
                    fid = fopen(Dir, 'w');
                    if fid == -1
                        error('Cannot open log file.');
                    end

                    if app.chart.Velocity < 0
                        positionSensorFloor = string(ceil(app.chart.Cur_Floor));
                    elseif app.chart.Velocity > 0
                        positionSensorFloor = string(floor(app.chart.Cur_Floor));
                    else
                        positionSensorFloor = "0";
                    end

                    fprintf(fid, '%s: %s\n', datestr(now, 0), ErrorMsg);
                    fprintf(fid, "Current Position: %s\n", app.chart.Cur_Floor);
%                     fprintf(fid, "Current Velocity: %s\n", app.chart.Velocity);
%                     fprintf(fid, "Current Acceleration: %s\n", app.chart.Acceleration);
%                     fprintf(fid, "Position Sensor last triggered: %s\n", positionSensorFloor);
                    fprintf(fid, "Door Status: %s\n", string(app.Status));
                    if isempty(app.Q)
                        fprintf(fid, "Outstanding Floor Requests: None\n")
                    else
                        fprintf(fid, "Outstanding Floor Requests: ")
                        [r,c] = size(app.Q);
                        for i = 1:c
                            fprintf(fid, "%d ", app.Q(i))
                        end
                        fprintf(fid, "\n");
                    end

                    if isempty(app.FloorRecord)
                        fprintf("Past Floor Requests: None\n");
                    else
                        fprintf(fid, "Past Floor Requests: ");
                        [r,c] = size(app.FloorRecord)
                        for i = 1:c
                            fprintf(fid, "%d ", app.FloorRecord(i));
                        end
                        fprintf(fid, "\n");
                    end
                    fclose(fid);
                end
            end
        end

        function FloorOveride(app, OverideFloor)
            app.Q = [];
            app.Q = round(OverideFloor);
            app.chart.Req_Floor = round(OverideFloor);
        end

        function updatePassengers(app)
            global passengerMatrix
            if isempty(passengerMatrix)
                app.chart.Passengers = 0;
            else
                [r,c] = size(passengerMatrix);
                app.chart.Passengers = r;
            end
        end

        function liftCall(app) % nextFloor changes from existing if redirection to be done
            global passengerMatrix
            global maxPassengers
            global liftFloors;

            fprintf(2, "________________________")

            Velocity = app.chart.Velocity;
            Cur_Floor = app.chart.Cur_Floor;
            % The original 5 input values
            if Velocity > 0.2
                liftDirection = 1;
            elseif Velocity < -0.2
                liftDirection = -1;
            else
                liftDirection = 0;
            end
            Floor = app.FloorSpinner.Value;  % ReqFloor
            Direction = app.liftCallDirection; % Do they want to go up or down?
            liftPosition = Cur_Floor; % where is the lift now?
            liftDestination = app.ReqFloor; % where is the lift going?
            nextFloor = app.nextFloor;

            fprintf(2, "[" + string(Floor) +" , " +  string(Direction) +" , " +  string(liftDirection) +" , " +  string(liftPosition) + " , " + string(liftDestination) + "\n");

            % Finds Average Demanded floor for Algorithm
            avgFloor = 0;
            if Direction == 1
                avgFloor = round(((liftFloors - Floor)/2) + Floor);
            else
                avgFloor = round(Floor / 2);
            end

            % Generates New User ID in the Passenger Matrix
            passenger = [double(Floor), now, avgFloor, 0, 0, Direction];

            global passengerMatrix % Adds to Passenger Matrix, also checks if empty
            if size(passengerMatrix,1) == 0
                passengerMatrix = passenger;
            else
                passengerMatrix = [passengerMatrix; passenger]
            end

            % Checks if request is en-route and that there is no urgent passenger
            overtimeFloor = overtimeCheck(app)

            nextFloor = 0;

            if double(overtimeFloor) ~= 0 %Determines if En-Route pickup OK
                if liftDirection == 1 && overtimeFloor > liftPosition && overtimeFloor < liftDestination
                    nextFloor = overtimeFloor;
                elseif liftDirection == -1 && overtimeFloor < liftPosition && overtimeFloor > liftDestination
                    nextFloor = overtimeFloor;
                else
                    nextFloor = liftDestination;
                end
            else
                if liftDirection == 0 % Lift is Stationary
                    nextFloor = Floor;
                elseif liftDirection == 1 && Floor > liftPosition && Direction == liftDirection && Floor < liftDestination
                    nextFloor = Floor;
                elseif liftDirection == -1 && Floor < liftPosition && Direction == liftDirection && Floor > liftDestination
                    nextFloor = Floor;
                else
                    nextFloor = liftDestination;
                end
            end

            % Checks if doing the re-route will cause too many passengers to be
            % picked up
            if nextFloor == Floor
                tempCounter = 0
                currentPassengers = 0
                for j = 1:size(passengerMatrix,1)
                    passengerMatrix(j,4)
                    if double(passengerMatrix(j,4)) ~= 0
                        currentPassengers = currentPassengers + 1
                    end
                    if double(passengerMatrix(j,5)) == Floor
                        tempCounter = tempCounter - 1
                    elseif double(passengerMatrix(j,1)) == Floor
                        tempCounter = tempCounter + 1
                    end
                end

                if currentPassengers + tempCounter > maxPassengers
                    nextFloor = liftDestination;
                end
            end


            %Check if enough time to slow down
            if abs(Floor - liftPosition) < 0.5
                nextFloor = liftDestination;
            end


            app.Q = [app.Q nextFloor];
            app.FloorRecord = [app.FloorRecord nextFloor];
            app.ReqFloor = nextFloor;
            app.nextFloor = nextFloor;
            fprintf(2,"nextFloor = " + string(nextFloor))
            fprintf(2, "___________")

        end

        function liftStopped(app)% buttonPressed = Internal Button
            global passengerMatrix;
            global maxPassengers;
            global liftFloors;

            %             fprintf(2,"hi")
            liftFloor = double(round(app.chart.Cur_Floor));

            [r,c] = size(app.liftButtons);
            if isempty(app.liftButtons)
                buttonPressed = 0;
            elseif (app.chart.Req_Floor - app.chart.Cur_Floor) < 0.3 && c >= 2 && app.liftButtons(1) == app.chart.Req_Floor
                buttonPressed = app.liftButtons(2:end);
                %             elseif (app.chart.Req_Floor - app.chart.Cur_Floor) < 0.3 && c == 1
                %                 buttonPressed = 0;
            else
                buttonPressed = app.liftButtons;

            end
            %             liftFloor = 3;
            %             buttonPressed = 6;
            %             fprintf(2, "Trial 1")
            disp(buttonPressed);
            %                         fprintf(2, "buttonPressed = " + string(buttonPressed(1))
            % Checks for passengers getting off on this floor, and assinging floors
            % to passengers getting on at the floor
            if ~isempty(buttonPressed)
                %                 button_1 = buttonPressed(1);
                fprintf(2, "liftStopped = [")
                [r,c] = size(buttonPressed);
                for i = 1:c
                    fprintf(2, buttonPressed(i) + ",");
                end
                fprintf(2, "]")
                %                 fprintf(2, "liftStopped = [" +string(liftFloor) + "," + string(button_1) + "]\t")
                %                 disp("liftStopped = [" + string(liftFloor) + "," + buttonPressed);
            end
            disp("Trial 1")

            % Checks for passengers getting off on this floor, and assinging floors
            % to passengers getting on at the floor
            buttonPressedTemp = buttonPressed;
            allocationCounter = 1;
            tempCounter = size(passengerMatrix,1);
            i = 1;
            while i <= tempCounter % goes through passenger matrix
                if double(passengerMatrix(i,5)) == liftFloor
                    if size(passengerMatrix,1) == 1
                        passengerMatrix(i,:) = []; % Deletes from Passenger Matrix if they got off here
                    else
                        passengerMatrix(i,:) = [];
                    end
                    tempCounter = tempCounter - 1;
                elseif double(passengerMatrix(i,1)) == liftFloor && double(passengerMatrix(i,4)) == 0
                    passengerMatrix(i,4) = now; % Adds wait time to Passenger Matrix if they got on here
                    passengerMatrix(i,5) = buttonPressedTemp(allocationCounter); %appends actual floor to person who got on here
                    if allocationCounter < length(buttonPressed) % Checks if multiple floor buttons pressed
                        allocationCounter = allocationCounter + 1; % as buttonPressed can be array, work through the list
                    end
                    i = i + 1;
                else
                    i = i + 1;
                end
            end

            if length(passengerMatrix) == 0 % If lift is empty, stop lift
                bestNextFloor = 0;
                return
            end

            stopArray = [];
            pickupArray = [];
            distinationArray = [];
            directionArray = [];
            passengerDone = [];
            stopFlag = 0;
            for i = 1:size(passengerMatrix,1) % Goes through passenger matrix
                pickupArray(i) = passengerMatrix(i,1); % Pickup location
                if double(passengerMatrix(i,5)) == 0
                    destinationArray(i) = passengerMatrix(i,3); % Dropoff Location (ESTIMATED)
                else
                    destinationArray(i) = passengerMatrix(i,5); % Dropoff Location (ACTUAL)
                end
                directionArray = [directionArray passengerMatrix(i,6)];
                if double(passengerMatrix(i,4)) == 0
                    passengerDone(i) = 1;
                else
                    passengerDone(i) = 2;
                end
            end

            minPath = 0;
            timeStop = 5;
            timeMotion = 1;
            timeArrayUp = []; % Where results of Up calculation are held
            locationArrayUp = [];
            timeArrayDown = []; % Where results of Down calculation are held
            locationArrayDown = [];
            for i = 1:liftFloors % Cycles through each floor (1-10)
                % START UP
                if i ~= liftFloors %Doesn't go up if on top floor
                    currentStops = 0;
                    currentMotion = 0;
                    Direction = 1;
                    floorTemp = i;
                    flag = 0;
                    bounceCounter = 0;
                    voidFlag = 0;

                    passengerDone = [];
                    for i2 = 1:size(passengerMatrix,1) % Goes through passenger matrix
                        if double(passengerMatrix(i2,4)) == 0
                            passengerDone(i2) = 1;
                        else
                            passengerDone(i2) = 2;
                        end
                    end
                    currentPath = abs(liftFloor - i)  * (length(passengerDone)) * (timeStop + timeMotion); % Cost to travel to current tested floor

                    currentJobArray = [];
                    for j = 1:size(passengerMatrix,1) % Cycles through the entire passsenger matrix to check for passengers in same direction
                        if directionArray(j) == Direction
                            currentJobArray = [currentJobArray pickupArray(j)];
                            currentJobArray = [currentJobArray destinationArray(j)];
                        end
                    end

                    sortedJobs = unique(sort(double(currentJobArray))); % Sorts array and removes duplicates for Up
                    startIndex = find(double(unique(sort([currentJobArray floorTemp]))) == double(floorTemp)); %%CHANGES THESE IN BELOW FOR GOING DOWN, FLIP SORT

                    currentJobArray = [];
                    for j = 1:size(passengerMatrix,1)
                        if directionArray(j) ~= Direction
                            currentJobArray = [currentJobArray pickupArray(j)];
                            currentJobArray = [currentJobArray destinationArray(j)];
                        end
                    end
                    sortedJobs2 = fliplr(unique(sort(double(currentJobArray)))); % repeat for opposite, flipped (higher floors first)

                    % Checks the index position of the nearest demanded floor, will
                    % set that floor as the first desitination

                    inuseArray = sortedJobs;
                    locationArray = [];

                    if startIndex > length(inuseArray)
                        startIndex = length(inuseArray);
                    end
                    if stopFlag ~= 1
                        while (ismember(1,passengerDone()) == 1 || ismember(2,passengerDone())); % While there is still demand
                            global maxPassengers; % Checks again for passenger overload
                            tempCounter = 0;
                            currentPassengers = 0;
                            if startIndex <= length(inuseArray) && startIndex > 0
                                for j = 1:size(passengerMatrix,1)
                                    if double(passengerMatrix(j,4)) ~= 0
                                        currentPassengers = currentPassengers + 1;
                                    end
                                    if double(passengerMatrix(j,5)) == inuseArray(startIndex)
                                        tempCounter = tempCounter - 1;
                                    elseif double(passengerMatrix(j,1)) == inuseArray(startIndex)
                                        tempCounter = tempCounter + 1;
                                    end
                                end
                                for j = 1:liftFloors
                                    if length(find(double(passengerMatrix(:,1)) == j)) > maxPassengers
                                        voidFlag = 1;
                                    end
                                end
                                if currentPassengers + tempCounter <= maxPassengers || voidFlag == 1
                                    for i2 = 1:size(passengerMatrix,1) % Checks each passenger and adjusts their current status at this gloor
                                        if directionArray(i2) == Direction
                                            if passengerDone(i2) == 1 && pickupArray(i2) == inuseArray(startIndex)
                                                passengerDone(i2) = 2;
                                                if flag ~= 1
                                                    locationArray(length(locationArray) + 1) = inuseArray(startIndex);
                                                    currentStops = currentStops + ((length(passengerDone) - sum(passengerDone(:) == 3)));
                                                    currentMotion = double(currentMotion) + (abs(double(inuseArray(startIndex)) - double(floorTemp)) * (double(length(passengerDone)) - double(sum(passengerDone(:) == 3))));
                                                    floorTemp = inuseArray(startIndex);
                                                    flag = 1;
                                                end
                                            end
                                            if passengerDone(i2) == 2 && double(destinationArray(i2)) == inuseArray(startIndex)
                                                passengerDone(i2) = 3;
                                                if flag ~= 1
                                                    locationArray(length(locationArray) + 1) = inuseArray(startIndex);
                                                    currentStops = currentStops + ((length(passengerDone) - (sum(passengerDone(:) == 3)-1)));
                                                    currentMotion = double(currentMotion) + (abs(double(inuseArray(startIndex)) - double(floorTemp)) * (double(length(passengerDone)) - double((sum(passengerDone(:) == 3)-1))));
                                                    floorTemp = inuseArray(startIndex);
                                                    flag = 1;
                                                end
                                            end
                                        end
                                    end
                                end
                            end
                            % Checks if the index position should be reset at end
                            % of floors
                            if Direction == 1 && startIndex >= length(inuseArray)
                                Direction = -1;
                                startIndex = 0;
                                inuseArray = sortedJobs2;
                            elseif Direction == -1 && startIndex >= length(inuseArray)
                                Direction = 1;
                                startIndex = 0;
                                inuseArray = sortedJobs;
                            end
                            startIndex = startIndex + 1;
                            flag = 0;
                        end
                    end
                    if stopFlag ~= 1
                        timeArrayUp(length(timeArrayUp)+1) = ((currentStops * timeStop) + (currentMotion * timeMotion)) + currentPath; % Added +1
                        locationArrayUp = [locationArrayUp locationArray(1)];
                    else
                        timeArrayDown(length(timeArrayDown)+1) = 999999999;
                        locationArrayDown = [locationArrayDown locationArrayUp(i-1)];
                    end
                    stopFlag = 0
                end

                % START DOWN
                if i ~= 1 %Doesn't go down if on bottom floor
                    currentStops = 0;
                    currentMotion = 0;
                    Direction = -1;
                    floorTemp = i;
                    flag = 0;
                    bounceCounter = 0;
                    voidFlag = 0;

                    passengerDone = [];
                    for i2 = 1:size(passengerMatrix,1) % Goes through passenger matrix
                        if double(passengerMatrix(i2,4)) == 0
                            passengerDone(i2) = 1;
                        else
                            passengerDone(i2) = 2;
                        end
                    end

                    %passengerDone
                    currentJobArray = [];
                    for j = 1:size(passengerMatrix,1) % Cycles through the entire passsenger matrix to check for passengers in same direction
                        if directionArray(j) ~= Direction
                            currentJobArray = [currentJobArray pickupArray(j)];
                            currentJobArray = [currentJobArray destinationArray(j)];
                        end
                    end

                    sortedJobs = unique(sort(double(currentJobArray))); % repeat for opposite

                    currentJobArray = [];
                    for j = 1:size(passengerMatrix,1)
                        if directionArray(j) == Direction
                            currentJobArray = [currentJobArray pickupArray(j)];
                            currentJobArray = [currentJobArray destinationArray(j)];
                        end
                    end

                    sortedJobs2 = fliplr(unique(sort(double(currentJobArray)))); % Sorts array and removes duplicates for Down, flipped
                    startIndex = find(double(unique(sort([currentJobArray floorTemp]))) == double(floorTemp));

                    % Checks the index position of the nearest demanded floor, will
                    % set that floor as the first desitination

                    inuseArray = sortedJobs;
                    locationArray = [];
                    if startIndex > length(inuseArray)
                        startIndex = length(inuseArray);
                    end
                    if length(sortedJobs2) == 0
                        stopFlag = 1;
                    end
                    if stopFlag ~= 1
                        while ismember(1,passengerDone()) == 1 || ismember(2,passengerDone()) % While there is still demand
                            global maxPassengers % Checks again for passenger overload
                            tempCounter = 0;
                            currentPassengers = 0;
                            startIndex;
                            if startIndex <= length(inuseArray) && startIndex > 0
                                for j = 1:size(passengerMatrix,1)
                                    if double(passengerMatrix(j,4)) ~= 0
                                        currentPassengers = currentPassengers + 1;
                                    end
                                    if double(passengerMatrix(j,5)) == inuseArray(startIndex)
                                        tempCounter = tempCounter - 1;
                                    elseif double(passengerMatrix(j,1)) == inuseArray(startIndex)
                                        tempCounter = tempCounter + 1;
                                    end
                                end

                                for j = 1:liftFloors
                                    if length(find(double(passengerMatrix(:,1)) == j)) > maxPassengers
                                        voidFlag = 1;
                                    end
                                end

                                if currentPassengers + tempCounter <= maxPassengers  || voidFlag == 1
                                    for i2 = 1:size(passengerMatrix,1) % Checks each passenger and adjusts their current status at this gloor
                                        if directionArray(i2) == Direction
                                            if passengerDone(i2) == 1 && pickupArray(i2) == inuseArray(startIndex)
                                                passengerDone(i2) = 2;
                                                if flag ~= 1
                                                    locationArray(length(locationArray) + 1) = inuseArray(startIndex);
                                                    currentStops = currentStops + ((length(passengerDone) - sum(passengerDone(:) == 3)));
                                                    currentMotion = double(currentMotion) + ( abs(double(inuseArray(startIndex)) - double(floorTemp))* (double(length(passengerDone)) - double(sum(passengerDone(:) == 3))));
                                                    floorTemp = inuseArray(startIndex);
                                                    flag = 1;
                                                end
                                            end
                                            if double(passengerDone(i2)) == 2 && double(destinationArray(i2)) == inuseArray(startIndex)
                                                passengerDone(i2) = 3;
                                                if flag ~= 1
                                                    locationArray(length(locationArray) + 1) = inuseArray(startIndex);
                                                    currentStops = currentStops + ((length(passengerDone) - sum(passengerDone(:) == 3)-1));
                                                    currentMotion = double(currentMotion) + (abs(double(inuseArray(startIndex)) - double(floorTemp)) * (double(length(passengerDone)) - double(sum(passengerDone(:) == 3)-1)));
                                                    floorTemp = inuseArray(startIndex);
                                                    flag = 1;
                                                end
                                            end
                                        end
                                    end
                                end
                            end

                            % Checks if the index position should be reset at end
                            % of floors
                            if Direction == 1 && double(startIndex) >= length(inuseArray)
                                Direction = -1;
                                startIndex = 0;
                                inuseArray = sortedJobs2;
                            elseif Direction == -1 && double(startIndex) >= length(inuseArray)
                                Direction = 1;
                                startIndex = 0;
                                inuseArray = sortedJobs;
                            end
                            startIndex = startIndex + 1;
                            flag = 0;
                        end
                    end
                    if stopFlag ~= 1
                        timeArrayDown(length(timeArrayDown)+1) = ((currentStops * timeStop) + (currentMotion * timeMotion)) + currentPath;
                        stopArray(length(stopArray)+1) = currentStops;
                        locationArrayDown = [locationArrayDown locationArray(1)];
                    else
                        timeArrayDown(length(timeArrayDown)+1) = 999999999;
                        stopArray(length(stopArray)+1) = currentStops;
                        locationArrayDown = [locationArrayDown locationArrayUp(i-1)];
                    end
                    stopFlag = 0;
                end
            end
            % Compares up and Down Times, picks the shortest and spits out the best next
            % floor to go to
            bestNextFloor = 0;
            sortUp = sort(timeArrayUp);
            sortDown = sort(timeArrayDown);
            if sortUp(1) < sortDown(1)
                bestNextFloor = locationArrayUp(find(timeArrayUp == sortUp(1))); % Extracts the index of the best next floor
            else
                bestNextFloor = locationArrayDown(find(timeArrayDown == sortDown(1)));
                if length(bestNextFloor) > 1
                    bestNextFloor = bestNextFloor(1);
                end
            end

            if overtimeCheck(app) ~= 0
                bestNextFloor = overtimeCheck(app);
            end
            app.Q = [app.Q bestNextFloor];
            app.FloorRecord = [app.FloorRecord bestNextFloor];
            fprintf(2,"bestNextFloor = " + bestNextFloor)

        end

        function priorityFloor = overtimeCheck(app)
            % Goes through Pass.Mtrx and checks for overtime
            global passengerMatrix
            overtimePos = [];
            overtimeWait = [];
            overtimeType = [];
            for i = 1:size(passengerMatrix,1)
                if now - double(passengerMatrix(i,2)) > 45000 && double(passengerMatrix(i,4)) == 0
                    overtimePos(length(overtimePos) + 1) = i;
                    overtimeWait(length(overtimeWait) + 1) = now - double(passengerMatrix(i,2)) - 45000; %Target Times in Milliseconds
                    overtimeType(length(overtimePos) + 1) = "Wait";
                elseif now - double(passengerMatrix(i,4)) > 45000  && double(passengerMatrix(i,4)) ~= 0
                    overtimePos(length(overtimePos) + 1) = i;
                    overtimeWait(length(overtimeWait) + 1) = now - double(passengerMatrix(i,4)) - 45000;
                    overtimeType(length(overtimePos) + 1) = "Lift";
                elseif ((now - (double(passengerMatrix(i,4))))+(double(passengerMatrix(i,4))-double(passengerMatrix(i,2)))) > 60000  && double(passengerMatrix(i,4)) ~= 0
                    overtimePos(length(overtimePos) + 1) = i;
                    overtimeWait(length(overtimeWait) + 1) = ((now - (double(passengerMatrix(i,4))))+(double(passengerMatrix(i,4))-double(passengerMatrix(i,2)))) - 60000;
                    overtimeType(length(overtimePos) + 1) = "Lift";
                end
            end

            % Checks if overtime present, where to go first.

            if length(overtimeWait) > 0
                sortWaitTimes = sort(overtimeWait);
                priorityIndex = find(overtimeWait == sortWaitTimes(length(sortWaitTimes)));
                priorityFloor = passengerMatrix(overtimePos(priorityIndex),1);
            else
                priorityFloor = 0;
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, chart)
            fprintf(2, "_______________________________________________________________________");
            app.chart = chart;               % access data in Stateflow chart
            assignin('base','app',app);      % access app from base workspaces
            startingValues(app);
            global passengerMatrix
            passengerMatrix = [];

            global maxPassengers
            maxPassengers = 5;

            global liftFloors
            liftFloors = 8;
            %             Lift Music
            lift_music = pwd + "/Audio/Lift_Music.mp3";
            [y, Lm] = audioread(lift_music);
            app.LM=audioplayer(y,Lm);
            play(app.LM);

            %             Alarm Bell
            alarm_bell = pwd + "/Audio/Alarm_bell.mp3";
            [y_ab, abs] = audioread(alarm_bell);
            app.AB = audioplayer(y_ab, abs);

            %             %             Arriving At Floor
            %             floor_1 = pwd + "/Audio/trial_a4.mp3";
            %             [f1, F1] = audioread(floor_1);
            %             app.Floor_1 = audioplayer(f1, F1);

            %             Doors Opening
            doors_op = pwd + "/Audio/Doors_Opening.mp3";
            [y_op, OP] = audioread(doors_op);
            app.DoorsOp = audioplayer(y_op,OP);

            %              Doors Closing
            doors_cl = pwd + "/Audio/Doors_Closing.mp3";
            [y_cl, CL] = audioread(doors_cl);
            app.DoorsCl = audioplayer(y_cl,CL);

            %             Floor
            floor = pwd + "/Audio/Floor.mp3";
            [y_f, F] = audioread(floor);
            app.Floor = audioplayer(y_f, F);

            %             One
            one = pwd + "/Audio/One.mp3";
            [y_1, O] = audioread(one);
            app.AOne = audioplayer(y_1, O);

            %             Two
            two = pwd + "/Audio/Two.mp3";
            [y_2, Tw] = audioread(two);
            app.ATwo = audioplayer(y_2, Tw);

            %             Three
            three = pwd + "/Audio/Three.mp3";
            [y_3, Thr] = audioread(three);
            app.AThree = audioplayer(y_3, Thr);

            %             Four
            four = pwd + "/Audio/Four.mp3";
            [y_4, Fou] = audioread(four);
            app.AFour = audioplayer(y_4, Fou);

            %             Five
            five = pwd + "/Audio/Five.mp3";
            [y_5, Fiv] = audioread(five);
            app.AFive = audioplayer(y_5, Fiv);

            %             Six
            six = pwd + "/Audio/Six.mp3";
            [y_6, Si] = audioread(six);
            app.ASix = audioplayer(y_6, Si);

            %             Seven
            seven = pwd + "/Audio/Seven.mp3";
            [y_7, Se] = audioread(seven);
            app.ASeven = audioplayer(y_7, Se);

            %             Eight
            eight = pwd + "/Audio/Eight.mp3";
            [y_8, Ei] = audioread(eight);
            app.AEight = audioplayer(y_8, Ei);

            %             Emergency
            emg = pwd + "/Audio/Emergency.mp3";
            [y_e, Em] =  audioread(emg);
            app.AEmg = audioplayer(y_e, Em);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            set_param('Simulink_Model','SimulationCommand', 'stop');
        end

        % Callback function
        function ButtonPushed(app, event)
            app.TextArea.Value = string(event.Source.Text);
        end

        % Button pushed function: Button_2, Button_3, Button_4, 
        % Button_5, Button_6, Button_7, Button_8, Button_9
        function InteriorFloorRequest(app, event)
            Floor_request(app, eval(event.Source.Text));
        end

        % Button pushed function: DOWNButton
        function DOWNButtonPushed(app, event)
            %             app.UpDownReq(1,end+1) = -1*app.FloorSpinner.Value;
            %             app.UpDownReq(2,end) = app.NoPassengersSpinner.Value;
            %             assignin('base','UpDownReq',app.UpDownReq);
            %             app.Lamp.Enable = 1;
            app.liftCallDirection = -1;
            liftCall(app);
            Floor_request(app, app.FloorSpinner.Value)

        end

        % Button pushed function: UPButton
        function UpButtonPushed(app, event)
            %             app.FloorReached()
            %             assignin('base','RqFloor',app.RqFloor);
            app.liftCallDirection = 1;
            liftCall(app);
            Floor_request(app, app.FloorSpinner.Value)
        end

        % Button pushed function: STOPButton
        function STOP(app, event)
            Emergency(app, 1);
        end

        % Button pushed function: NormalOperationButton
        function GO(app, event)
            Emergency(app, 0);
            app.FaultStateLamp.Enable = 0;
            app.NormalOperationLamp.Enable = 1;
        end

        % Button pushed function: Door_Close
        function DoorClose(app, event)
            DoorOperationReq(app,1);
        end

        % Button pushed function: Door_Open
        function DoorOpen(app, event)
            DoorOperationReq(app,0);
        end

        % Value changed function: FloorSpinner
        function FloorSpinnerValueChanged(app, event)
            app.Lamp.Enable = 0;
            if app.FloorSpinner.Value == 1
                app.DOWNButton.Visible = 0;
                app.DOWNButton.Enable = 0;
            elseif app.FloorSpinner.Value == 8
                app.UPButton.Visible = 0;
                app.UPButton.Enable = 0;
            else
                app.DOWNButton.Visible = 1;
                app.DOWNButton.Enable = 1;
                app.UPButton.Visible = 1;
                app.UPButton.Enable = 1;
            end
        end

        % Button pushed function: ObjectDetectionButton
        function ObjectDetectionToggle(app, event)
            if isequal(event.Source.Text, "Object: No")
                app.ObjectDetectionButton.Text = "Object: Yes";
                app.ObjectDetectionButton.BackgroundColor = [0 1 0];
                stop(app.LM);
                app.ObjectDetected = 1;
            elseif isequal(event.Source.Text, "Object: Yes")
                app.ObjectDetectionButton.Text = "Object: No";
                app.ObjectDetectionButton.BackgroundColor = [1 0 0];
                app.ObjectDetected = 0;
            end
        end

        % Button pushed function: AlarmStartButton
        function AlarmBell_Start(app, event)
            try
                %                 stop(app.Floor_1);
                stop(app.LM);
                play(app.AB);
            catch ME
                uiwait(msgbox('Could not open that file with audioread'));
            end
        end

        % Button pushed function: AlarmStopButton
        function AlarmBell_Stop(app, event)
            try
                stop(app.AB);
                play(app.LM);
            catch ME
                uiwait(msgbox('Could not open that file with audioread'));
            end
        end

        % Callback function
        function GroundFloor(app, event)
            Floor_request(app, 0);
        end

        % Callback function
        function ParkingFloor(app, event)
            Floor_request(app, -1);
        end

        % Button pushed function: DirectionUp
        function ExtDisDirection(app, event)

        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            FloorOveride(app, app.Slider.Value);

        end

        % Value changed function: ManualOverideSwitch
        function ManualOveride(app, event)
            if isequal(app.ManualOverideSwitch.Value, 'On')
                Emergency(app,3); % 3 meaning Manual Overide
            elseif isequal(app.ManualOverideSwitch.Value, 'Off')
                Emergency(app,0);
            end

        end

        % Button pushed function: ResetButton
        function Reset(app, event)
            %             app.forPresentation = 1;
            %             startingValues(app);
            global passengerMatrix
            passengerMatrix = [];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CurrentFloorEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentFloorEditFieldLabel.Text = 'Current Floor';
            app.CurrentFloorEditField = uieditfield(app.UIFigure, 'text');
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @InteriorFloorRequest, true);
            app.Button_2.Text = '1';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @InteriorFloorRequest, true);
            app.Button_3.Text = '2';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @InteriorFloorRequest, true);
            app.Button_4.Text = '3';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @InteriorFloorRequest, true);
            app.Button_5.Text = '4';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @InteriorFloorRequest, true);
            app.Button_6.Text = '5';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @InteriorFloorRequest, true);
            app.Button_7.Text = '6';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @InteriorFloorRequest, true);
            app.Button_8.Text = '7';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @InteriorFloorRequest, true);
            app.Button_9.Text = '8';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOP, true);
            app.STOPButton.Text = 'STOP';
            app.MOVINGButton = uibutton(app.UIFigure, 'push');
            app.MOVINGButton.Text = 'MOVING';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp.Enable = 'off';
            app.TextArea = uitextarea(app.UIFigure);
            app.NormalOperationButton = uibutton(app.UIFigure, 'push');
            app.NormalOperationButton.ButtonPushedFcn = createCallbackFcn(app, @GO, true);
            app.NormalOperationButton.Text = 'Normal Operation';
            app.UPButton = uibutton(app.UIFigure, 'push');
            app.UPButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UPButton.Text = 'UP';
            app.DOWNButton = uibutton(app.UIFigure, 'push');
            app.DOWNButton.ButtonPushedFcn = createCallbackFcn(app, @DOWNButtonPushed, true);
            app.DOWNButton.Enable = 'off';
            app.DOWNButton.Visible = 'off';
            app.DOWNButton.Text = 'DOWN';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_2.Enable = 'off';
            app.FloorSpinnerLabel = uilabel(app.UIFigure);
            app.FloorSpinnerLabel.Text = 'Floor';
            app.FloorSpinner = uispinner(app.UIFigure);
            app.FloorSpinner.Limits = [1 8];
            app.FloorSpinner.ValueChangedFcn = createCallbackFcn(app, @FloorSpinnerValueChanged, true);
            app.FloorSpinner.Value = 6;
            app.Door_Close = uibutton(app.UIFigure, 'push');
            app.Door_Close.ButtonPushedFcn = createCallbackFcn(app, @DoorClose, true);
            app.Door_Close.Text = 'Doors Close ><';
            app.Door_Open = uibutton(app.UIFigure, 'push');
            app.Door_Open.ButtonPushedFcn = createCallbackFcn(app, @DoorOpen, true);
            app.Door_Open.Text = 'Doors Open <>';
            app.DoorStatusTextAreaLabel = uilabel(app.UIFigure);
            app.DoorStatusTextAreaLabel.Text = 'Door Status';
            app.DoorStatusTextArea = uitextarea(app.UIFigure);
            app.Ext_User_Display = uieditfield(app.UIFigure, 'text');
            app.ObjectDetectionButton = uibutton(app.UIFigure, 'push');
            app.ObjectDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @ObjectDetectionToggle, true);
            app.ObjectDetectionButton.Text = 'Object: No';
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'A button will appear below when users are able to leave/enter the lift. '; 'This button simulates users passing through the doors and activating the ObjectDetection function. '};
            app.RequestedFloorLabel = uilabel(app.UIFigure);
            app.RequestedFloorLabel.Text = 'Requested Floor';
            app.TestText = uieditfield(app.UIFigure, 'text');
            app.AlarmStartButton = uibutton(app.UIFigure, 'push');
            app.AlarmStartButton.ButtonPushedFcn = createCallbackFcn(app, @AlarmBell_Start, true);
            app.AlarmStartButton.Text = 'Alarm Start';
            app.AlarmStopButton = uibutton(app.UIFigure, 'push');
            app.AlarmStopButton.ButtonPushedFcn = createCallbackFcn(app, @AlarmBell_Stop, true);
            app.AlarmStopButton.Text = 'Alarm Stop';
            app.Gauge = uigauge(app.UIFigure, 'semicircular');
            app.Gauge.Limits = [1 8];
            app.Gauge.MajorTicks = [1 2 3 4 5 6 7 8];
            app.Gauge.MinorTicks = [1.5 2.5 3.5 4.5 5.5 6.5 7.5];
            app.Gauge.Value = 1;
            app.DirectionUp = uibutton(app.UIFigure, 'push');
            app.DirectionUp.ButtonPushedFcn = createCallbackFcn(app, @ExtDisDirection, true);
            app.DirectionUp.Text = '↑';
            app.DirectionDown = uibutton(app.UIFigure, 'push');
            app.DirectionDown.Text = '↓';
            app.TextArea_6 = uitextarea(app.UIFigure);
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [-1 8];
            app.Slider.MajorTicks = [-1 0 1 2 3 4 5 6 7 8];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            app.NormalOperationLampLabel = uilabel(app.UIFigure);
            app.NormalOperationLampLabel.Text = 'Normal Operation';
            app.NormalOperationLamp = uilamp(app.UIFigure);
            app.DoorsinOperationsLampLabel = uilabel(app.UIFigure);
            app.DoorsinOperationsLampLabel.Text = 'Doors in Operations';
            app.DoorsinOperationsLamp = uilamp(app.UIFigure);
            app.FaultStateLampLabel = uilabel(app.UIFigure);
            app.FaultStateLampLabel.Text = 'Fault State';
            app.FaultStateLamp = uilamp(app.UIFigure);
            app.PositionEditFieldLabel = uilabel(app.UIFigure);
            app.PositionEditFieldLabel.Text = 'Position';
            app.PositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.VelocityEditFieldLabel = uilabel(app.UIFigure);
            app.VelocityEditFieldLabel.Text = 'Velocity';
            app.VelocityEditField = uieditfield(app.UIFigure, 'numeric');
            app.AccelerationEditFieldLabel = uilabel(app.UIFigure);
            app.AccelerationEditFieldLabel.Text = 'Acceleration';
            app.AccelerationEditField = uieditfield(app.UIFigure, 'numeric');
            app.StoppedStatusEditFieldLabel = uilabel(app.UIFigure);
            app.StoppedStatusEditFieldLabel.Text = 'Stopped Status';
            app.StoppedStatusEditField = uieditfield(app.UIFigure, 'text');
            app.ManualOverideSwitchLabel = uilabel(app.UIFigure);
            app.ManualOverideSwitchLabel.Text = 'Manual Overide';
            app.ManualOverideSwitch = uiswitch(app.UIFigure, 'toggle');
            app.ManualOverideSwitch.ValueChangedFcn = createCallbackFcn(app, @ManualOveride, true);
            app.ErrorMessageEditFieldLabel = uilabel(app.UIFigure);
            app.ErrorMessageEditFieldLabel.Text = 'Error Message';
            app.ErrorMessageEditField = uieditfield(app.UIFigure, 'text');
            app.InternalDisplayLabel = uilabel(app.UIFigure);
            app.InternalDisplayLabel.Text = 'Internal Display';
            app.ExternalDisplayLabel = uilabel(app.UIFigure);
            app.ExternalDisplayLabel.Text = 'External Display';
            app.OperatorsDisplayLabel = uilabel(app.UIFigure);
            app.OperatorsDisplayLabel.Text = 'Operator''s Display';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @Reset, true);
            app.ResetButton.Text = 'Reset';
            app.TextArea_7 = uitextarea(app.UIFigure);
            app.TextArea_8 = uitextarea(app.UIFigure);
            app.TextArea_8.Editable = 'off';
            app.TextArea_8.Value = {'This button only resets the passengerMatrix for Demonstration purposes'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppWSimulink(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end